classdef HW2_11202125 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        OpenMenu             matlab.ui.container.Menu
        SaveAsMenu           matlab.ui.container.Menu
        ExitMenu             matlab.ui.container.Menu
        ProcessMenu          matlab.ui.container.Menu
        NegativeMenu         matlab.ui.container.Menu
        LRFlipMenu           matlab.ui.container.Menu
        DarkerMenu           matlab.ui.container.Menu
        DifferenceMenu       matlab.ui.container.Menu
        GrayScaleMenu        matlab.ui.container.Menu
        EdgeDetectionMenu    matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        FadeoutsecLabel      matlab.ui.control.Label
        BoxcolorButton       matlab.ui.control.Button
        FadeoutsecEditField  matlab.ui.control.NumericEditField
        Button               matlab.ui.control.StateButton
        Label                matlab.ui.control.Label
        Image                matlab.ui.control.Image
        RightPanel           matlab.ui.container.Panel
        UITable              matlab.ui.control.Table
        Label2               matlab.ui.control.Label
        Image_2              matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
properties (Access = private)
        vidObj %視訊物件
        im %當下的畫面
        im_prev %前一幀的畫面
        p = 1 %影像處理選項
        im2; % 處理後的畫面
        T; % 字幕表格資料
        logo; % 台科 Logo 影像
        logo_mask; % Logo 的遮罩
        boxColor = [1 1 0]; % 字幕框的顏色
        subtitleBackgroundColor = [255 255 0]; % 字幕框
    end
    
    methods (Access = private)
        
        function results = func(app) %影像處理
            switch app.p %選項
                case 1 %反白
                    results = 255- app.im; % 對影像進行反白處理
                    app.Label2.Text = 'Negative'; % 在 UI 中顯示處理結果為 "Negative"

                case 2 %左右翻轉
                    results = fliplr(app.im); % 對影像進行左右翻轉處理
                    app.Label2.Text = 'LR Flip'; % 在 UI 中顯示處理結果為 "LR Flip"

                case 3 %變暗
                    results = app.im - 64; % 對影像進行變暗處理
                    app.Label2.Text = 'Darker'; % 在 UI 中顯示處理結果為 "Darker"

                case 4 %影像時間差異
                    results = imabsdiff(app.im,app.im_prev); % 計算當前影像與前一幀影像的差異
                    app.im_prev = app.im; % 將當前影像保存為前一幀影像，以便下一次計算差異
                    app.Label2.Text = 'Temporal Difference'; % 在 UI 中顯示處理結果為 "Temporal Difference"

                case 5 % 二值化
                    gray_image = im2gray(app.im);
                    
                    % 將灰階影像轉換為RGB格式
                    results = ind2rgb(gray_image, gray(256));
                    app.Label2.Text = 'GrayScale';

                case 6 %邊緣檢測
                    % 對灰度圖像進行邊緣檢測，使用"Sobel"方法
                    gray_image = im2gray(app.im);
                    edge_image = edge(gray_image, 'Sobel');
                
                    % 將二值化的邊緣圖像轉換為RGB格式
                    edge_image_rgb = ind2rgb(edge_image, [1 1 1; 0 0 0]);
                    
                    results = edge_image_rgb;
                    app.Label2.Text = 'Edge Detection'; % 在 UI 中顯示處理結果為 "Edge Detection"
            end
        end


        function updateSubtitles(app)
        end
     
        function insertLogo(app)
            % 1. 讀取視訊的下一幀影像
            app.im = readFrame(app.vidObj);
            
            % 2. 處理字幕和特效
            im_with_subtitle_and_effects = applyEffectsAndSubtitle(app);
            
            % 3. 讀取logo圖像和其透明度通道
            [app.logo, ~, alphaChannel] = imread('Chinese7.png');
            
            % 4. 製作透明度遮罩
            mask = im2double(alphaChannel); % 轉換透明度通道為double類型
            app.logo = im2double(app.logo); % 轉換logo圖像為double類型
            im_with_subtitle_and_effects = im2double(im_with_subtitle_and_effects); % 轉換處理後的影像為double類型

            % 5. 將logo置入畫面
            im_with_subtitle_and_effects(1:1+size(app.logo, 1)-1, 1:1+size(app.logo, 2)-1, :) = app.logo .* mask + im_with_subtitle_and_effects(1:1+size(app.logo, 1)-1, 1:1+size(app.logo, 2)-1, :) .* (1-mask);

            % 6. 應用淡出效果
            fadeoutTime = app.FadeoutsecEditField.Value; % 從 UI 中獲取淡出時間
            totalFrames = floor(app.vidObj.Duration * app.vidObj.FrameRate); % 計算總幀數
            frameRate = app.vidObj.FrameRate; % 視訊的幀率
            currentTime = app.vidObj.CurrentTime; % 當前視訊時間
            timeRemaining = app.vidObj.Duration - currentTime; % 剩餘時間
            fadeoutRatio = min(1, timeRemaining / fadeoutTime); % 計算淡出比例
            im_with_subtitle_and_effects = im_with_subtitle_and_effects .* fadeoutRatio; % 淡出處理

            % 7. 更新界面上的 Image 元件，顯示合成後的影像
            im_with_subtitle_and_effects_and_logo = im_with_subtitle_and_effects;
            pause(1/app.vidObj.FrameRate); % 暫停一段時間以匹配視訊的幀率
            app.Image_2.ImageSource = im_with_subtitle_and_effects_and_logo;
        end

        function im_with_subtitle_and_effects = applyEffectsAndSubtitle(app)
            % 讀取視訊的下一幀影像
            app.im = readFrame(app.vidObj);
            % 插入字幕
            im_with_subtitle_and_effects = insertText(func(app), [180, 300], findSubtitle(app), 'FontSize', 30, 'BoxColor', app.subtitleBackgroundColor, 'Font', 'Microsoft YaHei');
        end

        function subtitle = findSubtitle(app)
             % 取得當前時間
             currentTime = getCurrentTime(app);
            
             % 遍歷 UITable 尋找相符的字幕
             for i = 1:size(app.UITable.Data, 1) % 迴圈遍歷 UITable 中的每一行
                 startTime = app.UITable.Data{i, 1}; % 取得開始時間
                 endTime = app.UITable.Data{i, 2}; % 取得結束時間
                 subtitleText = app.UITable.Data{i, 3}; % 取得字幕文本
                
                 % 將時間字串轉換為數值
                 startTimeNum = str2double(startTime); % 將開始時間字串轉換為數值
                 endTimeNum = str2double(endTime); % 將結束時間字串轉換為數值
                
                 % 判斷當前時間是否在時間範圍內
                if currentTime >= startTimeNum && currentTime <= endTimeNum
                    subtitle = subtitleText;
                    return;
                end
            end
            
            % 如果沒有找到符合的字幕，則傳回空字串
            subtitle = '';
        end

        function currentTime = getCurrentTime(app)
             % 取得目前影片時間
             currentTime = app.vidObj.CurrentTime;
         end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 呼叫 tableFilter 函式，讀取 subtitles.txt 並轉換成 UITable.Data
            data = tableFilter(app, 'subtitles.txt');
            app.UITable.RowName = 1:8; % Row 的編號
            
            % 將轉換後的資料填充到 UITable 中
            app.UITable.Data = data;

            % 讀取 logo.png 檔案
            app.logo = imread('Chinese7.png');

            app.BoxcolorButton.BackgroundColor = app.boxColor;

            %applyEffectsAndSubtitle(app);
            
        end
        
        % tableFilter 函式用於將 subtitles.txt 轉換成 UITable.Data
        function data = tableFilter(~, filename)
            % 讀取 subtitles.txt 檔案
            fileID = fopen(filename,'r'); 
            % 讀取第一行（欄位名稱），並丟棄
            fgetl(fileID);
            
            % 初始化儲存轉換後資料的變數
            data = cell(0, 3); % 4 欄位的空白儲存格陣列
            
            % 讀取每一行資料，並進行轉換
            tline = fgetl(fileID); % 讀取一行資料
            while ischar(tline) % 當讀取的資料是字元陣列時（即未達到文件末尾）
                % 使用正則表達式從每一行中提取時間戳、字幕內容
                tokens = regexp(tline, '\[(.*?)\s*->\s*(.*?)\]\s*(.*)', 'tokens');
                if ~isempty(tokens)
                    % 解析時間戳
                    start_time = tokens{1}{1}(4:end); % 提取開始時間
                    end_time = tokens{1}{2}(4:end); % 提取結束時間
                    % 解析字幕內容
                    subtitle = tokens{1}{3}; % 提取字幕內容
                    
                    % 將解析後的資料存入儲存格陣列中
                    data{end+1, 1} = start_time; % 存入開始時間
                    data{end, 2} = end_time; % 存入結束時間
                    data{end, 3} = subtitle; % 存入字幕內容
                end
                % 讀取下一行
                tline = fgetl(fileID); 
            end
            
            % 關閉檔案
            fclose(fileID);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {730, 730};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %讀取視訊路徑檔名
            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            %建立視訊物件
            app.vidObj = VideoReader([pathname filename]); % 使用 VideoReader 類建立視訊物件，以便後續操作
            app.im = read(app.vidObj, 1); %讀取第一幀的影像
            app.Image.ImageSource = app.im; %顯示到Image圖框
            app.im_prev = app.im; %暫存(用於比較影像差異)
            app.Image_2.ImageSource = func(app); % 呼叫 func 函式處理影像，並將結果顯示到 UI 界面上的 Image_2 圖框
            app.Button.Icon = 'down.jpg'; % 設置按鈕的圖標為 'down.jpg'
            app.Label.Text = '0'; % 設置標籤的文本為 '0'
            figure(app.UIFigure) ;% 顯示應用的主要 UI 圖形窗口
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.p = 1;
            app.Image_2.ImageSource = func(app);
            app.FadeoutsecEditField.Visible = 'on';
            app.FadeoutsecLabel.Visible = 'on'; 
            app.BoxcolorButton.Visible = 'on';
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.p = 2;
            app.Image_2.ImageSource = func(app);
            app.FadeoutsecEditField.Visible = 'on';
            app.FadeoutsecLabel.Visible = 'on'; 
            app.BoxcolorButton.Visible = 'on';
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.p = 3;
            app.Image_2.ImageSource = func(app);
            app.FadeoutsecEditField.Visible = 'on';
            app.FadeoutsecLabel.Visible = 'on'; 
            app.BoxcolorButton.Visible = 'on';
        end

        % Callback function
        function ButtonPushed(app, event)
            read(app.vidObj,1);
            app.Button.Icon = 'pause.jpg';

            while hasFrame(app.vidObj)
                app.im = readFrame(app.vidObj);
                pause(1/app.vidObj.FrameRate);
                app.Label.Text = num2str(app.vidObj.CurrentTime);
                app.Image.ImageSource = app.im;
                % app.Image_2.ImageSource = func(app);
            end
            app.Button.Icon = 'down.jpg';
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
        value = app.Button.Value; % 獲取按鈕的值（按下為 1，未按下為 0）
        
            % 讀取 logo.png 影像
            app.logo = imread('Chinese7.png');
        
            if value % 如果按鈕被按下（值為 1）
                read(app.vidObj,1); % 讀取視訊的第一幀
                app.Button.Icon = 'pause.jpg'; % 將按鈕圖示設置為暫停圖示
        
                while hasFrame(app.vidObj) % 當還有視訊幀可讀取時執行迴圈
                    if app.Button.Value == 0 % 如果按鈕的值變為未按下（值為 0），則退出迴圈
                        break;
                    end
                    insertLogo(app);
        
                    app.im = readFrame(app.vidObj); % 讀取視訊的下一幀影像
                    pause(1/app.vidObj.FrameRate); % 暫停一段時間以匹配視訊的幀率
        
                    app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f'); % 將目前視訊時間顯示在標籤中
                    app.Image.ImageSource = app.im; % 在 Image 元件中顯示當前的影像
                    
                    % app.Image_2.ImageSource = func(app); % 進行影像處理並在 Image_2 元件中顯示結果
                end
            else % 如果按鈕的值變為未按下（值為 0）
                app.Button.Icon = 'down.jpg'; % 將按鈕圖示設置為下降箭頭圖示
                % 在此處添加代碼以停止視訊讀取操作，例如 app.vidObj.CurrentTime = app.vidObj.Duration;
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)

            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            vidObj2 = VideoWriter([pathname filename],'MPEG-4'); %建立待寫入的mp4視訊物件
            vidObj2.Quality = 90; % default quality = 75
            open(vidObj2) %開啟視訊物件
            read(app.vidObj,1); %讀第一幀
        
            % 獲取淡出時間
            fadeoutTime = app.FadeoutsecEditField.Value;
        
            % 獲取視訊總幀數和幀率
            totalFrames = floor(app.vidObj.Duration * app.vidObj.FrameRate);
            frameRate = app.vidObj.FrameRate;
        
            currentFrame = 0;
            while hasFrame(app.vidObj) %如果還有畫面，就寫入影像
                % 讀取下一幀影像
                currentFrame = currentFrame + 1;

                im_with_subtitle_and_effects = applyEffectsAndSubtitle(app);
        
                % 讀取logo圖像和其透明度通道
                [app.logo, ~, alphaChannel] = imread('Chinese7.png');
        
                % 製作透明度遮罩
                mask = im2double(alphaChannel);
                app.logo = im2double(app.logo);
                im_with_subtitle_and_effects = im2double(im_with_subtitle_and_effects);
                
                % 將logo置入畫面
                im_with_subtitle_and_effects(1:1+size(app.logo, 1)-1, 1:1+size(app.logo, 2)-1, :) = app.logo .* mask + im_with_subtitle_and_effects(1:1+size(app.logo, 1)-1, 1:1+size(app.logo, 2)-1, :) .* (1-mask);
        
                % 計算距離影片結束還有多少時間
                timeRemaining = app.vidObj.Duration - currentFrame / frameRate;
        
                % 計算影片的亮度調整比例
                fadeoutRatio = min(1, timeRemaining / fadeoutTime);
        
                % 調整影像的亮度
                im_with_subtitle_and_effects = im_with_subtitle_and_effects .* fadeoutRatio;

                % 如果 app.p 的值為 5 或 6，則將影像值正規化到範圍 [0, 1]
                %if app.p == 5 || app.p == 6
                %    im_with_subtitle_and_effects = mat2gray(im_with_subtitle_and_effects);
                %end
        
                % 寫入處理後的影像
                writeVideo(vidObj2, im_with_subtitle_and_effects);
            end
            close(vidObj2); %關閉 vidObj2 視訊物件        
        end

        % Menu selected function: DifferenceMenu
        function DifferenceMenuSelected(app, event)
            app.p = 4;
            app.Image_2.ImageSource = func(app);
            app.FadeoutsecEditField.Visible = 'on';
            app.FadeoutsecLabel.Visible = 'on'; 
            app.BoxcolorButton.Visible = 'on';
        end

        % Button pushed function: BoxcolorButton
        function BoxcolorButtonPushed(app, event)
            C = uisetcolor('Set Color'); % 彩色選擇介面程式, C 是RGB值, 範圍[0 1]
            app.boxColor = C;

            % 更新BoxcolorButton的顏色
            app.BoxcolorButton.BackgroundColor = app.boxColor;

            % 調整顏色值範圍為 [0,255]
            rgb_color = round(C * 255);
            
            % 將選擇的顏色設置為字幕的底色
            app.subtitleBackgroundColor = rgb_color;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            editedData = event.EditData; % 編輯後的數據
            editedRow = event.Indices(1); % 編輯的行索引
            editedColumn = event.Indices(2); % 編輯的列索引
      
            % 檢查時間格式是否合法
            if ~ischar(editedData) || isempty(regexp(editedData, '^\d{1,2}\.\d{3}$', 'once'))
                % 如果時間格式不合法，提示用戶並將該單元格的值恢復為原先的值
                uialert(app.UIFigure, '時間格式應為「xx.xxx」(秒.毫秒)，請重新輸入。', '錯誤', 'Icon', 'error');
                app.UITable.Data{editedRow, editedColumn} = event.PreviousData;
                return;
            end

            % 驗證時間範圍是否合法
            startTime = str2double(app.UITable.Data{editedRow, 1}); % 該行的起始時間
            endTime = str2double(app.UITable.Data{editedRow, 2}); % 該行的結束時間
            editedTime = str2double(editedData); % 編輯後的時間
        
            if editedColumn == 1 % 如果編輯的是起始時間
                if editedTime >= endTime
                    uialert(app.UIFigure, '起始時間應早於結束時間，請重新輸入。', '警告', 'Icon', 'warning');
                    app.UITable.Data{editedRow, editedColumn} = event.PreviousData;
                    return;
                end
            elseif editedColumn == 2 % 如果編輯的是結束時間
                if editedTime <= startTime
                    uialert(app.UIFigure, '結束時間應晚於起始時間，請重新輸入。', '警告', 'Icon', 'warning');
                    app.UITable.Data{editedRow, editedColumn} = event.PreviousData;
                    return;
                end
            end

            % 檢查與其他行的時間範圍是否重疊
            for row = 1:size(app.UITable.Data, 1)
                if row ~= editedRow % 排除當前行
                    rowStartTime = str2double(app.UITable.Data{row, 1}); % 其他行的起始時間
                    rowEndTime = str2double(app.UITable.Data{row, 2}); % 其他行的結束時間
                    if (editedTime >= rowStartTime && editedTime <= rowEndTime) || ...
                       (startTime >= rowStartTime && startTime <= rowEndTime)
                        uialert(app.UIFigure, '時間範圍與其他字幕重疊，請重新輸入。', '警告', 'Icon', 'warning');
                        app.UITable.Data{editedRow, editedColumn} = event.PreviousData;
                        return;
                    end
                end
            end

            % 如果所有的防呆检查通过，更新表格中的数据
            app.UITable.Data{editedRow, editedColumn} = editedData;
        end

        % Value changed function: FadeoutsecEditField
        function FadeoutsecEditFieldValueChanged(app, event)
            value = app.FadeoutsecEditField.Value;

            % 驗證淡出時間是否在合理的範圍內
            if value < 0 || value > app.vidObj.Duration
                % 如果淡出時間超出範圍，提示用戶並將該欄位的值恢復為原先的值
                uialert(app.UIFigure, '淡出時間應在合理的範圍內，請重新輸入。', '錯誤', 'Icon', 'error');
                app.FadeoutsecEditField.Value = event.PreviousValue;
            end;
            
        end

        % Menu selected function: GrayScaleMenu
        function GrayScaleMenuSelected(app, event)
            app.p = 5;
            app.Image_2.ImageSource = func(app);
            app.FadeoutsecEditField.Visible = 'off';
            app.FadeoutsecLabel.Visible = 'off'; 
            app.BoxcolorButton.Visible = 'off';
        end

        % Menu selected function: EdgeDetectionMenu
        function EdgeDetectionMenuSelected(app, event)
            app.p = 6;
            app.Image_2.ImageSource = func(app);
            app.FadeoutsecEditField.Visible = 'off';
            app.FadeoutsecLabel.Visible = 'off'; 
            app.BoxcolorButton.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.NegativeMenu = uimenu(app.ProcessMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.DifferenceMenu = uimenu(app.ProcessMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferenceMenuSelected, true);
            app.DifferenceMenu.Text = 'Difference';
            app.GrayScaleMenu = uimenu(app.ProcessMenu);
            app.GrayScaleMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayScaleMenuSelected, true);
            app.GrayScaleMenu.Text = 'GrayScale';
            app.EdgeDetectionMenu = uimenu(app.ProcessMenu);
            app.EdgeDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeDetectionMenuSelected, true);
            app.EdgeDetectionMenu.Text = 'Edge Detection';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Label = uilabel(app.LeftPanel);
            app.Button = uibutton(app.LeftPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'pause.jpg';
            app.Button.Text = '';
            app.FadeoutsecEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FadeoutsecEditField.ValueChangedFcn = createCallbackFcn(app, @FadeoutsecEditFieldValueChanged, true);
            app.BoxcolorButton = uibutton(app.LeftPanel, 'push');
            app.BoxcolorButton.ButtonPushedFcn = createCallbackFcn(app, @BoxcolorButtonPushed, true);
            app.BoxcolorButton.Text = 'Box color';
            app.FadeoutsecLabel = uilabel(app.LeftPanel);
            app.FadeoutsecLabel.Text = 'Fade out (sec)';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'Label2';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Start'; 'End'; 'Subtitles'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW2_11202125

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end